classdef Linear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LiqutrnhPanel     matlab.ui.container.Panel
        TextArea          matlab.ui.control.TextArea
        thPanel           matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        DliuPanel         matlab.ui.container.Panel
        MatrnOutputLabel  matlab.ui.control.Label
        MatrnInputLabel   matlab.ui.control.Label
        OutputTable       matlab.ui.control.Table
        InputTable        matlab.ui.control.Table
        Panel             matlab.ui.container.Panel
        TnhtonButton      matlab.ui.control.Button
        ResetButton       matlab.ui.control.Button
        TobngButton       matlab.ui.control.Button
        NumRowandCol      matlab.ui.control.NumericEditField
        ShngvctLabel      matlab.ui.control.Label
        thvhngButton      matlab.ui.control.Button
        thchngButton      matlab.ui.control.Button
        TI08THUTTONFLOYDWARSHALLTMNGINGNNHTLabel  matlab.ui.control.Label
        CN01Nhm8Label     matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: thvhngButton
        function thvhngButtonPushed(app, event)
            app.thchngButton.Enable="off";
            app.ResetButton.Enable="on";

            app.NumRowandCol.Enable="on";
            app.TobngButton.Enable="on";

            app.thvhngButton.BackgroundColor=[0.07,0.62,1.00];
        end

        % Button pushed function: thchngButton
        function thchngButtonPushed(app, event)
            app.thvhngButton.Enable="off";
            app.ResetButton.Enable="on";

            app.NumRowandCol.Enable="on";
            app.TobngButton.Enable="on";

            app.thchngButton.BackgroundColor=[0.07,0.62,1.00];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.thvhngButton.Enable="on";
            app.thchngButton.Enable="on";
            app.TnhtonButton.Enable="off";
            app.ResetButton.Enable="off";

            app.NumRowandCol.Enable="off";
            app.TobngButton.Enable="off";

            app.thchngButton.BackgroundColor=[0.96,0.96,0.96];
            app.thvhngButton.BackgroundColor=[0.96,0.96,0.96];

            data = cell(0, 0);
            app.InputTable.Data = data;
            app.OutputTable.Data = data;

            app.NumRowandCol.Value=0;
        end

        % Button pushed function: TobngButton
        function TobngButtonPushed(app, event)
            app.TobngButton.Enable = "off";
            app.TnhtonButton.Enable="on";
            app.NumRowandCol.Enable="off";
            
            Num = round(app.NumRowandCol.Value);

            data = cell(Num, Num);

            app.InputTable.Data = data;
            app.OutputTable.Data = data;

            Names = arrayfun(@(x) sprintf('%d', x), 1:Num, 'UniformOutput', false);
            app.InputTable.ColumnName = Names;
            app.OutputTable.ColumnName = Names;
            app.OutputTable.RowName = Names;
            app.InputTable.RowName = Names;

            app.OutputTable.Enable = "off";
        end

        % Button pushed function: TnhtonButton
        function TnhtonButtonPushed(app, event)
            data = app.InputTable.Data;
            
            fileID = fopen('input.txt', 'w');
            [rows, cols] = size(data);
            for i = 1:rows
                for j = 1:cols
                    fprintf(fileID, '%s ', string(data{i, j}));
                end
                fprintf(fileID, '\n');
            end
            fclose(fileID);
            
            adjMatrix = cell2mat(data);
            adjMatrix = full(double(adjMatrix));
            
            % graphType = app.GraphTypeDropDown.Value;
            
            [src, dst, weights] = find(adjMatrix);
            G = graph(src, dst, weights);
            plot(app.UIAxes, G, 'Layout', 'force', 'EdgeLabel', G.Edges.Weight);


            %system('run.exe')
            %OutputData = readmatrix('Output.txt');
            %app.OutputTable.Data = OutputData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CN01Nhm8Label = uilabel(app.UIFigure);
            app.CN01Nhm8Label.Text = 'CN01 - Nhóm 8';
            app.TI08THUTTONFLOYDWARSHALLTMNGINGNNHTLabel = uilabel(app.UIFigure);
            app.TI08THUTTONFLOYDWARSHALLTMNGINGNNHTLabel.Text = 'ĐỀ TÀI 08: THUẬT TOÁN FLOYD - WARSHALL TÌM ĐƯỜNG ĐI NGẮN NHẤT';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.thchngButton = uibutton(app.Panel, 'push');
            app.thchngButton.ButtonPushedFcn = createCallbackFcn(app, @thchngButtonPushed, true);
            app.thchngButton.Text = 'Đồ thị có hướng';
            app.thvhngButton = uibutton(app.Panel, 'push');
            app.thvhngButton.ButtonPushedFcn = createCallbackFcn(app, @thvhngButtonPushed, true);
            app.thvhngButton.Text = 'Đồ thị vô hướng';
            app.ShngvctLabel = uilabel(app.Panel);
            app.ShngvctLabel.Text = {'Số hàng'; 'và cột'};
            app.NumRowandCol = uieditfield(app.Panel, 'numeric');
            app.NumRowandCol.RoundFractionalValues = 'on';
            app.NumRowandCol.Enable = 'off';
            app.TobngButton = uibutton(app.Panel, 'push');
            app.TobngButton.ButtonPushedFcn = createCallbackFcn(app, @TobngButtonPushed, true);
            app.TobngButton.Enable = 'off';
            app.TobngButton.Text = 'Tạo bảng';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.TnhtonButton = uibutton(app.Panel, 'push');
            app.TnhtonButton.ButtonPushedFcn = createCallbackFcn(app, @TnhtonButtonPushed, true);
            app.TnhtonButton.Enable = 'off';
            app.TnhtonButton.Text = 'Tính toán';
            app.DliuPanel = uipanel(app.UIFigure);
            app.DliuPanel.Title = 'Dữ liệu';
            app.InputTable = uitable(app.DliuPanel);
            app.InputTable.ColumnName = {'1'; '2'; '3'; '4'};
            app.InputTable.RowName = {'1,2,3,4'};
            app.InputTable.ColumnEditable = true;
            app.OutputTable = uitable(app.DliuPanel);
            app.OutputTable.ColumnName = {'1'; '2'; '3'; '4'};
            app.OutputTable.RowName = {'1,2,3,4'};
            app.OutputTable.ColumnEditable = true;
            app.OutputTable.Enable = 'off';
            app.MatrnInputLabel = uilabel(app.DliuPanel);
            app.MatrnInputLabel.Text = 'Ma trận Input';
            app.MatrnOutputLabel = uilabel(app.DliuPanel);
            app.MatrnOutputLabel.Text = 'Ma trận Output';
            app.thPanel = uipanel(app.UIFigure);
            app.thPanel.Title = 'Đồ thị';
            app.UIAxes = uiaxes(app.thPanel);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LiqutrnhPanel = uipanel(app.UIFigure);
            app.LiqutrnhPanel.Title = 'Lỗi quá trình';
            app.TextArea = uitextarea(app.LiqutrnhPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Linear

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end